classdef ET_RemoveArtifacts_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoaddatastructureMenu           matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        PlotsMenu                       matlab.ui.container.Menu
        PupilPlotMenu                   matlab.ui.container.Menu
        VelocityPlotMenu                matlab.ui.container.Menu
        DataMenu                        matlab.ui.container.Menu
        VerboseMenu                     matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        IndexSpinnerLabel               matlab.ui.control.Label
        IndexSpinner                    matlab.ui.control.Spinner
        VelocityPlotPanel               matlab.ui.container.Panel
        VelocityCheckBox                matlab.ui.control.CheckBox
        BlinkOnsetVelocityCheckBox      matlab.ui.control.CheckBox
        BlinkOffsetVelocityCheckBox     matlab.ui.control.CheckBox
        VelocityPlotLabel               matlab.ui.control.Label
        PupilPlotPanel                  matlab.ui.container.Panel
        OriginalPupilCheckBox           matlab.ui.control.CheckBox
        ReconstructedPupilCheckBox      matlab.ui.control.CheckBox
        OutputPupilCheckBox             matlab.ui.control.CheckBox
        BlinkOnsetPupilCheckBox         matlab.ui.control.CheckBox
        BlinkOffsetPupilCheckBox        matlab.ui.control.CheckBox
        PupilPlotLabel                  matlab.ui.control.Label
        ExcludeCheckBox                 matlab.ui.control.CheckBox
        DetectBlinksPanel               matlab.ui.container.Panel
        DetectBlinksCheckBox            matlab.ui.control.CheckBox
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        FilterOrderEditField            matlab.ui.control.NumericEditField
        PeakBoundaryThresholdLabel      matlab.ui.control.Label
        PeakBoundaryThresholdEditField  matlab.ui.control.NumericEditField
        TroughBoundaryThresholdLabel    matlab.ui.control.Label
        TroughBoundaryThresholdEditField  matlab.ui.control.NumericEditField
        PassbandFrequencyLabel          matlab.ui.control.Label
        PassbandFrequencyEditField      matlab.ui.control.NumericEditField
        StopbandFrequencyLabel          matlab.ui.control.Label
        StopbandFrequencyEditField      matlab.ui.control.NumericEditField
        PeakThresholdFactorLabel        matlab.ui.control.Label
        PeakThresholdFactorEditField    matlab.ui.control.NumericEditField
        TroughThresholdFactorLabel      matlab.ui.control.Label
        TroughThresholdFactorEditField  matlab.ui.control.NumericEditField
        GeneralPreprocessingPanel       matlab.ui.container.Panel
        ResamplingRateHzEditField_2Label  matlab.ui.control.Label
        ResampleRateEditField           matlab.ui.control.NumericEditField
        ResamplingMultiplierEditFieldLabel  matlab.ui.control.Label
        ResampleMultiplierEditField     matlab.ui.control.NumericEditField
        GeneralPreprocessingLabel       matlab.ui.control.Label
        DetectInvalidSamplesPanel       matlab.ui.container.Panel
        DetectInvalidSamplesCheckBox    matlab.ui.control.CheckBox
        FrontPaddingsEditFieldLabel     matlab.ui.control.Label
        FrontPaddingEditField           matlab.ui.control.NumericEditField
        RearPaddingsEditFieldLabel      matlab.ui.control.Label
        RearPaddingEditField            matlab.ui.control.NumericEditField
        MergeInvalidsGapsEditFieldLabel  matlab.ui.control.Label
        MergeInvalidsGapEditField       matlab.ui.control.NumericEditField
        ValidRangeUpper01Label          matlab.ui.control.Label
        ValidRangeUpperEditField        matlab.ui.control.NumericEditField
        RangeCheckBox                   matlab.ui.control.CheckBox
        ValidRangeLower01Label          matlab.ui.control.Label
        ValidRangeLowerEditField        matlab.ui.control.NumericEditField
        ApplyAlgorithmButton            matlab.ui.control.Button
        ManuallyEditPlotButton          matlab.ui.control.Button
        InterpolationOptionsPanel       matlab.ui.container.Panel
        MergeArtifactsGapsEditFieldLabel  matlab.ui.control.Label
        MergeArtifactsGapEditField      matlab.ui.control.NumericEditField
        MaxArtifactDurationsEditFieldLabel  matlab.ui.control.Label
        MaxArtifactDurationEditField    matlab.ui.control.NumericEditField
        MaxArtifactTreatmentButtonGroup  matlab.ui.container.ButtonGroup
        IgnoreButton                    matlab.ui.control.RadioButton
        InterpolateButton               matlab.ui.control.RadioButton
        NaNImputeButton                 matlab.ui.control.RadioButton
        InterpolationOptionsLabel       matlab.ui.control.Label
        VelocityPlotAxis                matlab.ui.control.UIAxes
        PupilPlotAxis                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        S                   % data structure
        sub_index = 1;      % index corresponding to the data structure
        saved = 1;          % tracks save state for last changes (1 = changes have been saved; 0 = changes have not been saved)
        edited = 0;         % tracks whether user has manually edited plot; ApplyAlgorithm checks this value before overwriting any manual edits
        file                % contains sub-fields name and folder related to the file
        VelocityPlot
        BlinkOnsetVelocityPlot
        BlinkOffsetVelocityPlot
        OriginalPupilPlot
        ReconstructedPupilPlot
        OutputPupilPlot
        BlinkOnsetPupilPlot
        BlinkOffsetPupilPlot
        RangePlot
    end
    
    methods (Access = private)
        
        function app = update_plots(app)
            % Updates plots only if the associated CheckBox is enabled
            
            % Velocity plots
            velocity_pairs = {{'Velocity','velocity'},{'BlinkOnsetVelocity','blink_onset'},{'BlinkOffsetVelocity','blink_offset'}};
            for vel_num = 1:numel(velocity_pairs)
                if strcmp(app.([velocity_pairs{vel_num}{1} 'CheckBox']).Enable,'on')
                    app.([velocity_pairs{vel_num}{1} 'Plot']).XData = app.S(app.sub_index).(velocity_pairs{vel_num}{2}).vel_timestamp;
                    app.([velocity_pairs{vel_num}{1} 'Plot']).YData = app.S(app.sub_index).(velocity_pairs{vel_num}{2}).velocity;
                end
            end
            
            % Pupil plots
            pupil_pairs = {{'OriginalPupil','data'},{'ReconstructedPupil','reconstructed'},{'OutputPupil','output'},{'BlinkOnsetPupil','blink_onset'},{'BlinkOffsetPupil','blink_offset'}};
            for pup_num = 1:numel(pupil_pairs)
                if strcmp(app.([pupil_pairs{pup_num}{1} 'CheckBox']).Enable,'on')
                    app.([pupil_pairs{pup_num}{1} 'Plot']).XData = app.S(app.sub_index).(pupil_pairs{pup_num}{2}).smp_timestamp;
                    app.([pupil_pairs{pup_num}{1} 'Plot']).YData = app.S(app.sub_index).(pupil_pairs{pup_num}{2}).sample;
                end
            end
            
            % Range Plot
            update_range_plot(app);
        end
        
        function app = resample_to_sampling_rate(app)
            % Resamples data from the multiplier sampling rate to the user-defined
            % sampling rate. This matters if the resample multiplier is not 1.
            %
            % Also, replaces samples with NaN instead of interpolating over NaN (which is the default for resample)
                        
            % Find NaN timestamps
            NaN_la = isnan(app.S(app.sub_index).reconstructed.sample);
            NaN_start_indices = find(diff([0;NaN_la])==1);
            NaN_end_indices = find(diff([NaN_la;0])==-1);
            NaN_start_ts =  app.S(app.sub_index).reconstructed.smp_timestamp(NaN_start_indices);
            NaN_end_ts = app.S(app.sub_index).reconstructed.smp_timestamp(NaN_end_indices);
            
            % Resample reconstructed samples and save to output field
            resample_rate = app.S(app.sub_index).filter_config.resample_rate;
            [app.S(app.sub_index).output.sample,app.S(app.sub_index).output.smp_timestamp] = resample(app.S(app.sub_index).reconstructed.sample,app.S(app.sub_index).reconstructed.smp_timestamp,resample_rate,1,1);
            
            % Replaces samples in output field with NaN where appropriate
            output_NaN_la = false(numel(app.S(app.sub_index).output.sample),1);   % create logical array of length of sample
            for i=1:numel(NaN_start_ts)
                output_NaN_la = output_NaN_la | (app.S(app.sub_index).output.smp_timestamp >= NaN_start_ts(i) & app.S(app.sub_index).output.smp_timestamp <= NaN_end_ts(i));
            end
            app.S(app.sub_index).output.sample(output_NaN_la) = NaN;
        end
        
        function app = turn_on_or_off(app, app_fieldnames, on_or_off)
            % Enables any user-specified fieldname of app (unless the field
            % is a handle to a line - make it invisible)
            
            for i = 1:numel(app_fieldnames)
                class_split = strsplit(class(app.(app_fieldnames{i})), '.');
                if strcmp(class_split{end}, 'Line')
                    % If the field is a line, make it Visible:
                    app.(app_fieldnames{i}).Visible = on_or_off;
                else
                    % If the field is some other ui component, make Enable it:
                    app.(app_fieldnames{i}).Enable = on_or_off;
                end
            end
        end
        
        function app = turn_all_plot_checkboxes(app, on_off_state)
            % Enables all plot-associated checkboxes (ignores checkboxes
            % like ExcludeCheckBox)
            % on_off_state is either 'on' or 'off'. This is not to be
            % confused with the checkbox Values - this is just enabling or
            % disabling the checkboxes
            
            app_fieldnames = fieldnames(app);
            plot_checkbox_array = ~cellfun(@isempty,regexp(app_fieldnames,'(Pupil|Velocity)CheckBox$'));
            turn_on_or_off(app, app_fieldnames(plot_checkbox_array), on_off_state);
        end
        
        function app = disable_checkboxes_clear_plots(app, prefixes)
            % Disables plot-associated checkboxes and clear those plots in
            % user-defined prefixes cell array
            
            for prefix = prefixes
                app.([prefix{1} 'Plot']).XData = [];
                app.([prefix{1} 'Plot']).YData = [];
            end
            turn_on_or_off(app, strcat(prefixes, 'CheckBox'), 'off');
        end
        
        function app = update_new_data(app)
            % Updates the data that was loaded either by changing the
            % app.sub_index from changing the IndexSpinner value or by
            % loading in a new data structure from a .mat file.
            %
            % This fn is used in the ChangeIndex and LoadDataStructure
            % Callbacks
            %
            % 1. Populates the GUI edit fields with filter_config
            % parameters belonging to app.S(app.sub_index) (if exists)
            % 2. If all fields are present, simply plot everything. If only
            % some, but not all, fields are valid, selectively disable the
            % elements associated with the invalid fields. If no fields are
            % valid, run the algorithm before plotting everything
            
            % Populate GUI edit field with exclude value if the field
            % exists
            if isfield(app.S(app.sub_index),'Exclude') && ~isempty(app.S(app.sub_index).Exclude)
                app.ExcludeCheckBox.Value = app.S(app.sub_index).Exclude;
            else
                app.ExcludeCheckBox.Value = 0;
                app.S(app.sub_index).Exclude = 0;
            end
            
            % Check if data is a field of S, and if sub-fields of data
            % exist; if not, return
            if ~isfield(app.S(app.sub_index),'data') || ~isfield(app.S(app.sub_index).data, 'smp_timestamp') || ~isfield(app.S(app.sub_index).data, 'sample')
                app = disable_checkboxes_clear_plots(app, {'OriginalPupil','Velocity', 'BlinkOnsetVelocity','BlinkOffsetVelocity','BlinkOnsetPupil','BlinkOffsetPupil','ReconstructedPupil','OutputPupil'});
                warning('Incomplete data structure. Missing data or sub-fields of data.')
                return
            end
            
            % Detect the sampling rate and set it as default
            timestamp_diff = diff(app.S(app.sub_index).data.smp_timestamp(1:100));
            mode_timestamp_diff = mode(timestamp_diff);
            sample_rate = round(1/mode_timestamp_diff,-1);      % default sample_rate is the inverse of the mode of timestamp difference rounded to nearest 10's
            
            % Default parameters based on detected sampling rate
            app.ResampleRateEditField.Value = sample_rate;
            app.FilterOrderEditField.Value = sample_rate*.2-mod(sample_rate*.2,2);       % filter order default is 20% of resample_rate; also ensure that filter order is an even int
            
            % Populate GUI edit fields with filter_config parameters if
            % they exist
            if isfield(app.S(app.sub_index),'filter_config')
                uifield_filterconfig_pairs = {{'FilterOrderEditField','filter_order'},...      % define uifield_filterconfig_pairs {1} is editfield name {2} is filter_config sub-field
                    {'ResampleRateEditField','resample_rate'},...
                    {'ResampleMultiplierEditField','resample_multiplier'},...
                    {'DetectBlinksCheckBox','detect_blinks'},...
                    {'PeakBoundaryThresholdEditField','peak_boundary_threshold'},...
                    {'TroughBoundaryThresholdEditField','trough_boundary_threshold'},...
                    {'PassbandFrequencyEditField','passband_freq'},...
                    {'StopbandFrequencyEditField','stopband_freq'},...
                    {'PeakThresholdFactorEditField','peak_threshold_factor'},...
                    {'TroughThresholdFactorEditField','trough_threshold_factor'},...
                    {'DetectInvalidSamplesCheckBox','detect_invalid_samples'},...
                    {'FrontPaddingEditField','front_padding'},...
                    {'RearPaddingEditField','rear_padding'},...
                    {'MergeInvalidsGapEditField','merge_invalids_gap'},...
                    {'ValidRangeLowerEditField','valid_range_lower'},...
                    {'ValidRangeUpperEditField','valid_range_upper'},...
                    {'MergeArtifactsGapEditField','merge_artifacts_gap'},...
                    {'MaxArtifactDurationEditField','max_artifact_duration'}};
                for i = 1:numel(uifield_filterconfig_pairs)
                    if isfield(app.S(app.sub_index).filter_config, uifield_filterconfig_pairs{i}{2}) && ~isempty(app.S(app.sub_index).filter_config.(uifield_filterconfig_pairs{i}{2}))
                        app.(uifield_filterconfig_pairs{i}{1}).Value = app.S(app.sub_index).filter_config.([uifield_filterconfig_pairs{i}{2}]);
                    else
                        warning(['No ' uifield_filterconfig_pairs{i}{2} ' detected. Using default value to populate ' uifield_filterconfig_pairs{i}{1} '.'])
                    end
                end
                
                % If user defined the max artifact treatment, point to the
                % correct button
                if isfield(app.S(app.sub_index).filter_config, 'max_artifact_treatment') && ~isempty(app.S(app.sub_index).filter_config.max_artifact_treatment)
                    switch lower(app.S(app.sub_index).filter_config.max_artifact_treatment)
                        case 'ignore'
                            uibutton_name = 'IgnoreButton';
                        case 'interpolate'
                            uibutton_name = 'InterpolateButton';
                        case {'nanimpute', 'nan impute'}
                            uibutton_name = 'NaNImputeButton';
                        otherwise
                            warning('No max_artifact_treatment detected or value is invalid. Using default values.')
                    end
                    app.MaxArtifactTreatmentButtonGroup.SelectedObject = app.(uibutton_name);
                end
            end
                       
            % Find invalid fields (either does not exist or is not populated)
            S_fields = {'resampled','velocity','blink_onset','blink_offset','reconstructed','output'};
            invalid_S_fields = S_fields(cellfun(@(x) ~isfield(app.S, x) || isempty(app.S(app.sub_index).(x)), S_fields));

            % First enable all plots, then disable as required
            app = turn_all_plot_checkboxes(app, 'on');
            if numel(invalid_S_fields) == 0
                % No invalid fields: Enable all plot checkboxes without re-running algorithm
            elseif numel(invalid_S_fields) >= 1 && numel(invalid_S_fields) <= 5
                % Some but not all fields are valid: Clear plots
                % and disable their checkboxes if the associated 
                % field is invalid
                
                if any(strcmp(invalid_S_fields,'resampled'))
                    % Note Original Pupil Plot is the from resampled
                    app = disable_checkboxes_clear_plots(app, {'OriginalPupil'});
                end
                if any(strcmp(invalid_S_fields,'velocity'))
                    app = disable_checkboxes_clear_plots(app, {'Velocity', 'BlinkOnsetVelocity','BlinkOffsetVelocity'});
                end
                if any(strcmp(invalid_S_fields,'blink_onset'))
                    app = disable_checkboxes_clear_plots(app, {'BlinkOnsetVelocity','BlinkOnsetPupil'});
                end
                if any(strcmp(invalid_S_fields,'blink_offset'))
                    app = disable_checkboxes_clear_plots(app, {'BlinkOffsetVelocity','BlinkOffsetPupil'});
                end
                if any(strcmp(invalid_S_fields,'reconstructed'))
                    app = disable_checkboxes_clear_plots(app, {'ReconstructedPupil'});
                end
                if any(strcmp(invalid_S_fields,'output'))
                    app = disable_checkboxes_clear_plots(app, {'OutputPupil'});
                end
                
                % Generate warning dlg and command window warning
                warn_msg = ['Detected fields generated by ET-Remove-Artifacts. However, some fields may be missing or unpopulated. Please re-run algorithm to generate the following missing/incomplete fields: ' strjoin(invalid_S_fields, ', ')];
                warndlg(warn_msg, 'Fields Missing','modal');
                warning(warn_msg);
            elseif numel(invalid_S_fields) == 6
                % No fields are valid: Run algorithm and keep all plot
                % checkboxes enabled before updating the plots                
                ApplyAlgorithm(app);
            end
            
            % Update plots
            app = update_plots(app);
        end
        
        function rescale_x_axes(app)
            % Rescales x axes according to the XData (called after updating
            % plots)
            if strcmp(app.VelocityCheckBox.Enable,'on')
                app.VelocityPlotAxis.XLim = [app.VelocityPlot.XData(1) app.VelocityPlot.XData(end)];
            end
            if strcmp(app.OriginalPupilCheckBox.Enable,'on')
                app.PupilPlotAxis.XLim = [app.OriginalPupilPlot.XData(1) app.OriginalPupilPlot.XData(end)];
            end
        end
        
        function update_range_plot(app)
            % update_range_plot
            min_sample = min(app.S(app.sub_index).resampled.sample);
            max_sample = max(app.S(app.sub_index).resampled.sample);
            upper_range_abs = app.ValidRangeUpperEditField.Value*(max_sample-min_sample)+min_sample;
            lower_range_abs = app.ValidRangeLowerEditField.Value*(max_sample-min_sample)+min_sample;
            
            app.RangePlot.YData = [upper_range_abs upper_range_abs lower_range_abs lower_range_abs upper_range_abs];
            app.RangePlot.XData = [app.S(app.sub_index).resampled.smp_timestamp(1) app.S(app.sub_index).resampled.smp_timestamp(end) app.S(app.sub_index).resampled.smp_timestamp(end) app.S(app.sub_index).resampled.smp_timestamp(1) app.S(app.sub_index).resampled.smp_timestamp(1)];

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUpFcn(app)
            gray_color = [200, 200, 200]/255;
            desert_brown = [199, 144, 101]/255;
            forest_green = [102, 147, 12]/255;      
            onset_marker_color = [224, 169, 153]/255;
            offset_marker_color = [135, 44, 7]/255;
            
            % Set the Figure Position based on size of main display
            screen_size = get(0,'ScreenSize');
            app.UIFigure.Position(1) = 0.5*screen_size(3) - 0.5*app.UIFigure.Position(3);
            app.UIFigure.Position(2) = 0.5*screen_size(4) - 0.5*app.UIFigure.Position(4);
            
            % Initialize Velocity Axis Plots            
            app.VelocityPlot = line('Color', gray_color, 'LineStyle', '-',...
                'Parent', app.VelocityPlotAxis, 'Visible', 'Off');
            app.BlinkOnsetVelocityPlot = line('Color', onset_marker_color, 'LineStyle', 'none',...
                'Marker','.', 'MarkerSize', 15, 'Parent', app.VelocityPlotAxis, 'Visible', 'Off');
            app.BlinkOffsetVelocityPlot = line('Color', offset_marker_color, 'LineStyle', 'none',...
                'Marker','.', 'MarkerSize', 15, 'Parent', app.VelocityPlotAxis, 'Visible', 'Off');
                            
            % Initialize Pupil Axis Plots
            
            app.OriginalPupilPlot = line('Color', gray_color, 'LineStyle', '-',...
                'Parent', app.PupilPlotAxis, 'Visible', 'Off');
            app.ReconstructedPupilPlot = line('Color', desert_brown, 'LineStyle', '-',...
                'LineWidth',1,'Parent', app.PupilPlotAxis, 'Visible', 'Off');
            app.OutputPupilPlot = line('Color', forest_green, 'LineStyle', '-',...
                'LineWidth',1,'Parent', app.PupilPlotAxis, 'Visible', 'Off');
            app.BlinkOnsetPupilPlot = line('Color', onset_marker_color, 'LineStyle', 'none',...
                'Marker','.', 'MarkerSize', 15, 'Parent', app.PupilPlotAxis, 'Visible', 'Off');          
            app.BlinkOffsetPupilPlot = line('Color', offset_marker_color, 'LineStyle', 'none',...
                'Marker','.', 'MarkerSize', 15, 'Parent', app.PupilPlotAxis, 'Visible', 'Off');
            app.RangePlot = line('Color',gray_color*0.6, 'LineStyle', '--',...
                'Parent', app.PupilPlotAxis,'Visible','Off');
            
            % Tag the checkbox with the fieldname of the Plot that they are
            % associated with (for easier toggling)
            prefixes = regexp(fieldnames(app),'(.*)Plot$','tokens');        % prefixes are the chars that come before Plot$ ($ denotes Plot at the end of the char array)
            fieldname_prefixes = prefixes(~cellfun(@isempty,prefixes));     % fieldname_prefixes assigned only cells that have prefix
            for i = 1:numel(fieldname_prefixes)
                app.([fieldname_prefixes{i}{1}{1} 'CheckBox']).Tag = [fieldname_prefixes{i}{1}{1} 'Plot'];
            end
            
            warning Off backtrace
        end

        % Button pushed function: ApplyAlgorithmButton
        function ApplyAlgorithm(app, event)
            % Checks whether manual changes have been made for this plot - if so,
            % user is warned that filter plot will erase any manual changes
            % (WIP)
            
            progressDialog = uiprogressdlg(app.UIFigure,'Message','Applying artifact removal algorithm. Please wait...','Indeterminate','on');
            
            % Fetch algorithm configurations from the GUI
            app.S(app.sub_index).filter_config.sub_nums = app.sub_index;                % sub_nums specifieds which index of S to apply the algorithm
            app.S(app.sub_index).filter_config.resample_rate = app.ResampleRateEditField.Value;
            app.S(app.sub_index).filter_config.resample_multiplier = app.ResampleMultiplierEditField.Value;
            
            app.S(app.sub_index).filter_config.detect_blinks = app.DetectBlinksCheckBox.Value;
            app.S(app.sub_index).filter_config.filter_order = app.FilterOrderEditField.Value;
            app.S(app.sub_index).filter_config.peak_boundary_threshold = app.PeakBoundaryThresholdEditField.Value;
            app.S(app.sub_index).filter_config.trough_boundary_threshold = app.TroughBoundaryThresholdEditField.Value;
            app.S(app.sub_index).filter_config.passband_freq = app.PassbandFrequencyEditField.Value;
            app.S(app.sub_index).filter_config.stopband_freq = app.StopbandFrequencyEditField.Value;
            app.S(app.sub_index).filter_config.peak_threshold_factor = app.PeakThresholdFactorEditField.Value;
            app.S(app.sub_index).filter_config.trough_threshold_factor = app.TroughThresholdFactorEditField.Value;
            
            app.S(app.sub_index).filter_config.detect_invalid_samples = app.DetectInvalidSamplesCheckBox.Value;
            app.S(app.sub_index).filter_config.front_padding = app.FrontPaddingEditField.Value;
            app.S(app.sub_index).filter_config.rear_padding = app.RearPaddingEditField.Value;
            app.S(app.sub_index).filter_config.merge_invalids_gap = app.MergeInvalidsGapEditField.Value;
            app.S(app.sub_index).filter_config.valid_range_upper = app.ValidRangeUpperEditField.Value;
            app.S(app.sub_index).filter_config.valid_range_lower = app.ValidRangeLowerEditField.Value;
            
            app.S(app.sub_index).filter_config.merge_artifacts_gap = app.MergeArtifactsGapEditField.Value;
            app.S(app.sub_index).filter_config.max_artifact_duration = app.MaxArtifactDurationEditField.Value;
            app.S(app.sub_index).filter_config.max_artifact_treatment = app.MaxArtifactTreatmentButtonGroup.SelectedObject.Tag;
            
            % Run ET_RemoveArtifacts_Algorithm
            app.S = ET_RemoveBlinks_Algorithm(app.S,app.S(app.sub_index).filter_config);
            
            % Resample to user-defined sampling rate (reconstructed)
            app = resample_to_sampling_rate(app);
            
            % Enable all Checkboxes for plot toggling (this must precede
            % update plot in order for all plots to be updated)
            app = turn_all_plot_checkboxes(app, 'on');
            
            % Update Plots
            app = update_plots(app);
            
            % Indicate changes have not been saved
            app.saved = 0;
            
            delete(progressDialog)
        end

        % Menu selected function: LoaddatastructureMenu
        function LoadDataStructure(app, event)
            % Query whether user wants to discard unsaved changes if
            % previous changes have not been saved
            if app.saved == 0
                resp = questdlg('Loading new data will discard any unsaved changes. Continue?','Continue?','Yes','No','No');
                switch resp
                    case 'Yes'
                        app.sub_index = 1;
                    otherwise       % if user selects no or exits dialog, return function
                        return
                end
            end
            
           progressDialog = uiprogressdlg(app.UIFigure,'Message','Loading your data. Please wait...','Indeterminate','on');
            
            if isfield(app.file,'name') && isfield(app.file,'folder')
                % If a file has already been loaded:
                [name, folder] = uigetfile('*.mat','Select File',[app.file.name]);
            else
                [name, folder] = uigetfile('*.mat','Select File');
            end
            
            % Check if user selected a file (instead of just canceling):
            if ischar(name) && ischar(folder)
                % Assign name and folder of the file
                app.file.name = name;
                app.file.folder = folder;
            else
                % Return if user canceled
                return;
            end
            

            % Save data structure S to app.S
            app.saved = 1;                              % Reset saved state to 1
            load(fullfile(folder,name),'-mat','S');     % Loads variable S
            app.S = S;                                  % Assign S to app.S
            app.sub_index = 1;                          % Reset sub_index to 1
            if app.IndexSpinner.Limits(1) < numel(app.S)
                app.IndexSpinner.Limits(2) = numel(app.S);      % Set new upper limit for IndexSpinner; Limit(2) must be greater than Limit(1)
                turn_on_or_off(app, {'IndexSpinner'}, 'on');    % enable IndexSpinner if app.S has > 1 element
            else
                turn_on_or_off(app, {'IndexSpinner'}, 'off');   % disable IndexSpinner if app.S only has one element
            end
            app.IndexSpinner.Value = 1;                 % Reset to 1
            
            % Bring app.UIFigure back to foreground
            app.UIFigure.Visible = 'Off';
            app.UIFigure.Visible = 'On';
            
            % Determine what to do with newly-loaded data depending on the
            % valid fields
            app = update_new_data(app);
            
            % Rescale X Axis
            rescale_x_axes(app);

            % Make Some Plots Visible
            app_fieldnames = fieldnames(app);
            turn_on_or_off(app, app_fieldnames(~cellfun(@isempty,regexp(app_fieldnames,'VelocityPlot$'))), 'on');       % Use $ b/c plot names end with 'Plot'
            turn_on_or_off(app, {'OriginalPupilPlot','OutputPupilPlot'}, 'on');                                     %Only make these two pupil plots visible
            
            % Enable Elements (Note: do not enable all the plot-associated
            % CheckBoxes)
            turn_on_or_off(app, app_fieldnames(~cellfun(@isempty,regexp(app_fieldnames,'EditField$'))), 'on');
            turn_on_or_off(app, app_fieldnames(~cellfun(@isempty,regexp(app_fieldnames,'Button$'))), 'on');
            turn_on_or_off(app, app_fieldnames(~cellfun(@isempty,regexp(app_fieldnames,'Label$'))), 'on');
            turn_on_or_off(app, {'SaveasMenu','SaveMenu','ExcludeCheckBox','DetectBlinksCheckBox','DetectInvalidSamplesCheckBox','RangeCheckBox'}, 'on');
            delete(progressDialog);
        end

        % Value changed function: BlinkOffsetPupilCheckBox, 
        % BlinkOffsetVelocityCheckBox, BlinkOnsetPupilCheckBox, 
        % BlinkOnsetVelocityCheckBox, OriginalPupilCheckBox, 
        % OutputPupilCheckBox, RangeCheckBox, 
        % ReconstructedPupilCheckBox, VelocityCheckBox
        function PlotToggle(app, event)
            if event.Source.Value == 1
                on_or_off = 'on';
            else
                on_or_off = 'off';
            end
            
            app = turn_on_or_off(app, {event.Source.Tag}, on_or_off);     % Turn on or off plot visibility
        end

        % Value changed function: IndexSpinner
        function ChangeIndex(app, event)
            % Change current sub_index
            app.sub_index = app.IndexSpinner.Value;           
            
            % Updates GUI with the new app.sub_index
            app = update_new_data(app);
            
            % Rescale X Axis
            rescale_x_axes(app);
        end

        % Button pushed function: ManuallyEditPlotButton
        function LaunchManualEditor(app, event)
            % Callback associated with Manually Edit Plot button

            figure_handle2 = ET_EditArtifacts_Manual_GUI(app);
            if isvalid(figure_handle2)
                h2 = guidata(figure_handle2);
                if h2.save == 1
                    % Overwrite data if user selected save
                    app.S(app.sub_index).reconstructed.sample = h2.reconstructed.sample;
                    app.S(app.sub_index).reconstructed.smp_timestamp = h2.reconstructed.smp_timestamp;
                    
                    % Resample to orginial sampling rate and save to output
                    app = resample_to_sampling_rate(app);
                    
                end
                delete(figure_handle2);
            end
            
            % Update plots
            app = update_plots(app);
            
            app.S(app.sub_index).manual_changes = 1;
            app.saved = 0;      % indicate changes have not been saved
        end

        % Menu selected function: SaveMenu, SaveasMenu
        function SaveDataStructure(app, event)
            
            switch event.Source.Text
                case 'Save as'
                    [name, folder] = uiputfile('*.mat','Save Data Structure S as');
                    if ischar(name) && ischar(folder)
                        app.file.name = name;
                        app.file.folder = folder;
                    else
                        return;     % do not save if no valid name was selected
                    end
                case 'Save'
                    % just use app.file.name
            end
            
            progressDialog = uiprogressdlg(app.UIFigure,'Message','Saving data structure to file. Please wait...','Indeterminate','on');
            
            %% ***Note for future implementation: I'll consider adding an option for the user to enable/disable compression instead of doing it based off file size
            % Save data structure S
            S = app.S;
            var_info = whos('S');
            
            if var_info.bytes > 2*10^9          % if variable bytes exceeds 2GB
                % save without compression (Pros: faster; Cons: larger file
                % size)
                save(fullfile(app.file.folder, app.file.name),'S','-v7.3','-nocompression');
            else
                % save with compression (Pros: smaller file size; Cons:
                % slower)
                save(fullfile(app.file.folder, app.file.name),'S');
            end
            
            % Indicate file saved
            app.saved = 1;
            
            delete(progressDialog)
        end

        % Value changed function: ExcludeCheckBox
        function TagExclude(app, event)
            
            app.S(app.sub_index).Exclude = app.ExcludeCheckBox.Value;
            app.saved = 0;
            
        end

        % Value changed function: FilterOrderEditField, 
        % PassbandFrequencyEditField, StopbandFrequencyEditField
        function CheckEditFieldValues(app, event)
           
            pause(0.05)     % give a chance for GUI element to update
            
            if event.Source == app.FilterOrderEditField
                if mod(event.Value,2) == 1      % if newly entered filter order is odd
                    app.FilterOrderEditField.Value = event.PreviousValue;
                    uialert(app.UIFigure,'Filter Order must be even.','Even Values','Icon','warning')
                end
            elseif event.Source == app.PassbandFrequencyEditField
                if app.PassbandFrequencyEditField.Value >= app.StopbandFrequencyEditField.Value
                    app.PassbandFrequencyEditField.Value = event.PreviousValue;
                    uialert(app.UIFigure,'Passband Frequency must be less than Stopband Frequency.','Even Values','Icon','warning')                    
                end
            elseif event.Source == app.StopbandFrequencyEditField
                if app.StopbandFrequencyEditField.Value <= app.PassbandFrequencyEditField.Value
                    app.StopbandFrequencyEditField.Value = event.PreviousValue;
                    uialert(app.UIFigure,'Stopband Frequency must be greater than Passband Frequency.','Even Values','Icon','warning')
                end
            end
            
        end

        % Value changed function: ValidRangeLowerEditField, 
        % ValidRangeUpperEditField
        function ChangeRange(app, event)
            update_range_plot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ET Remove Artifacts';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '   File   ';
            app.LoaddatastructureMenu = uimenu(app.FileMenu);
            app.LoaddatastructureMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataStructure, true);
            app.LoaddatastructureMenu.Text = 'Load data structure';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataStructure, true);
            app.SaveasMenu.Enable = 'off';
            app.SaveasMenu.Text = 'Save as';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataStructure, true);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Enable = 'off';
            app.ViewMenu.Text = '   View   ';
            app.PlotsMenu = uimenu(app.ViewMenu);
            app.PlotsMenu.Enable = 'off';
            app.PlotsMenu.Text = 'Plots';
            app.PupilPlotMenu = uimenu(app.PlotsMenu);
            app.PupilPlotMenu.Enable = 'off';
            app.PupilPlotMenu.Checked = 'on';
            app.PupilPlotMenu.Text = 'Pupil Plot';
            app.VelocityPlotMenu = uimenu(app.PlotsMenu);
            app.VelocityPlotMenu.Enable = 'off';
            app.VelocityPlotMenu.Checked = 'on';
            app.VelocityPlotMenu.Text = 'Velocity Plot';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Enable = 'off';
            app.DataMenu.Text = '   Data   ';
            app.VerboseMenu = uimenu(app.DataMenu);
            app.VerboseMenu.Enable = 'off';
            app.VerboseMenu.Text = 'Verbose';
            app.Panel = uipanel(app.UIFigure);
            app.IndexSpinnerLabel = uilabel(app.Panel);
            app.IndexSpinnerLabel.Enable = 'off';
            app.IndexSpinnerLabel.Text = 'Index';
            app.IndexSpinner = uispinner(app.Panel);
            app.IndexSpinner.Limits = [1 Inf];
            app.IndexSpinner.RoundFractionalValues = 'on';
            app.IndexSpinner.ValueChangedFcn = createCallbackFcn(app, @ChangeIndex, true);
            app.IndexSpinner.Enable = 'off';
            app.IndexSpinner.Value = 1;
            app.VelocityPlotPanel = uipanel(app.Panel);
            app.VelocityCheckBox = uicheckbox(app.VelocityPlotPanel);
            app.VelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotToggle, true);
            app.VelocityCheckBox.Enable = 'off';
            app.VelocityCheckBox.Text = 'Velocity (Gray)';
            app.VelocityCheckBox.Value = true;
            app.BlinkOnsetVelocityCheckBox = uicheckbox(app.VelocityPlotPanel);
            app.BlinkOnsetVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotToggle, true);
            app.BlinkOnsetVelocityCheckBox.Enable = 'off';
            app.BlinkOnsetVelocityCheckBox.Text = 'Blink Onset (Pink)';
            app.BlinkOnsetVelocityCheckBox.Value = true;
            app.BlinkOffsetVelocityCheckBox = uicheckbox(app.VelocityPlotPanel);
            app.BlinkOffsetVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotToggle, true);
            app.BlinkOffsetVelocityCheckBox.Enable = 'off';
            app.BlinkOffsetVelocityCheckBox.Text = 'Blink Offset (Red)';
            app.BlinkOffsetVelocityCheckBox.Value = true;
            app.VelocityPlotLabel = uilabel(app.VelocityPlotPanel);
            app.VelocityPlotLabel.Enable = 'off';
            app.VelocityPlotLabel.Text = 'Velocity Plot';
            app.PupilPlotPanel = uipanel(app.Panel);
            app.OriginalPupilCheckBox = uicheckbox(app.PupilPlotPanel);
            app.OriginalPupilCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotToggle, true);
            app.OriginalPupilCheckBox.Enable = 'off';
            app.OriginalPupilCheckBox.Text = 'Original (Gray)';
            app.OriginalPupilCheckBox.Value = true;
            app.ReconstructedPupilCheckBox = uicheckbox(app.PupilPlotPanel);
            app.ReconstructedPupilCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotToggle, true);
            app.ReconstructedPupilCheckBox.Enable = 'off';
            app.ReconstructedPupilCheckBox.Text = 'Reconstructed (Brown)';
            app.OutputPupilCheckBox = uicheckbox(app.PupilPlotPanel);
            app.OutputPupilCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotToggle, true);
            app.OutputPupilCheckBox.Enable = 'off';
            app.OutputPupilCheckBox.Text = 'Output (Green)';
            app.OutputPupilCheckBox.Value = true;
            app.BlinkOnsetPupilCheckBox = uicheckbox(app.PupilPlotPanel);
            app.BlinkOnsetPupilCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotToggle, true);
            app.BlinkOnsetPupilCheckBox.Enable = 'off';
            app.BlinkOnsetPupilCheckBox.Text = 'Blink Onset (Pink)';
            app.BlinkOffsetPupilCheckBox = uicheckbox(app.PupilPlotPanel);
            app.BlinkOffsetPupilCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotToggle, true);
            app.BlinkOffsetPupilCheckBox.Enable = 'off';
            app.BlinkOffsetPupilCheckBox.Text = 'Blink Offset (Red)';
            app.PupilPlotLabel = uilabel(app.PupilPlotPanel);
            app.PupilPlotLabel.Enable = 'off';
            app.PupilPlotLabel.Text = 'Pupil Plot';
            app.ExcludeCheckBox = uicheckbox(app.Panel);
            app.ExcludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TagExclude, true);
            app.ExcludeCheckBox.Enable = 'off';
            app.ExcludeCheckBox.Text = 'Exclude';
            app.DetectBlinksPanel = uipanel(app.Panel);
            app.DetectBlinksCheckBox = uicheckbox(app.DetectBlinksPanel);
            app.DetectBlinksCheckBox.Enable = 'off';
            app.DetectBlinksCheckBox.Text = 'Detect Blinks';
            app.DetectBlinksCheckBox.Value = true;
            app.FilterOrderEditFieldLabel = uilabel(app.DetectBlinksPanel);
            app.FilterOrderEditFieldLabel.Enable = 'off';
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.DetectBlinksPanel, 'numeric');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @CheckEditFieldValues, true);
            app.FilterOrderEditField.Enable = 'off';
            app.FilterOrderEditField.Value = 20;
            app.PeakBoundaryThresholdLabel = uilabel(app.DetectBlinksPanel);
            app.PeakBoundaryThresholdLabel.Enable = 'off';
            app.PeakBoundaryThresholdLabel.Text = 'Peak Boundary Threshold';
            app.PeakBoundaryThresholdEditField = uieditfield(app.DetectBlinksPanel, 'numeric');
            app.PeakBoundaryThresholdEditField.Enable = 'off';
            app.TroughBoundaryThresholdLabel = uilabel(app.DetectBlinksPanel);
            app.TroughBoundaryThresholdLabel.Enable = 'off';
            app.TroughBoundaryThresholdLabel.Text = 'Trough Boundary Threshold';
            app.TroughBoundaryThresholdEditField = uieditfield(app.DetectBlinksPanel, 'numeric');
            app.TroughBoundaryThresholdEditField.Enable = 'off';
            app.PassbandFrequencyLabel = uilabel(app.DetectBlinksPanel);
            app.PassbandFrequencyLabel.Enable = 'off';
            app.PassbandFrequencyLabel.Text = 'Passband Frequency';
            app.PassbandFrequencyEditField = uieditfield(app.DetectBlinksPanel, 'numeric');
            app.PassbandFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CheckEditFieldValues, true);
            app.PassbandFrequencyEditField.Enable = 'off';
            app.PassbandFrequencyEditField.Value = 1;
            app.StopbandFrequencyLabel = uilabel(app.DetectBlinksPanel);
            app.StopbandFrequencyLabel.Enable = 'off';
            app.StopbandFrequencyLabel.Text = 'Stopband Frequency';
            app.StopbandFrequencyEditField = uieditfield(app.DetectBlinksPanel, 'numeric');
            app.StopbandFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CheckEditFieldValues, true);
            app.StopbandFrequencyEditField.Enable = 'off';
            app.StopbandFrequencyEditField.Value = 20;
            app.PeakThresholdFactorLabel = uilabel(app.DetectBlinksPanel);
            app.PeakThresholdFactorLabel.Enable = 'off';
            app.PeakThresholdFactorLabel.Text = 'Peak Threshold Factor';
            app.PeakThresholdFactorEditField = uieditfield(app.DetectBlinksPanel, 'numeric');
            app.PeakThresholdFactorEditField.Enable = 'off';
            app.PeakThresholdFactorEditField.Value = 5;
            app.TroughThresholdFactorLabel = uilabel(app.DetectBlinksPanel);
            app.TroughThresholdFactorLabel.Enable = 'off';
            app.TroughThresholdFactorLabel.Text = 'Trough Threshold Factor';
            app.TroughThresholdFactorEditField = uieditfield(app.DetectBlinksPanel, 'numeric');
            app.TroughThresholdFactorEditField.Enable = 'off';
            app.TroughThresholdFactorEditField.Value = 5;
            app.GeneralPreprocessingPanel = uipanel(app.Panel);
            app.ResamplingRateHzEditField_2Label = uilabel(app.GeneralPreprocessingPanel);
            app.ResamplingRateHzEditField_2Label.Enable = 'off';
            app.ResamplingRateHzEditField_2Label.Text = 'Resampling Rate (Hz)';
            app.ResampleRateEditField = uieditfield(app.GeneralPreprocessingPanel, 'numeric');
            app.ResampleRateEditField.Enable = 'off';
            app.ResampleRateEditField.Value = 120;
            app.ResamplingMultiplierEditFieldLabel = uilabel(app.GeneralPreprocessingPanel);
            app.ResamplingMultiplierEditFieldLabel.Enable = 'off';
            app.ResamplingMultiplierEditFieldLabel.Text = 'Resampling Multiplier';
            app.ResampleMultiplierEditField = uieditfield(app.GeneralPreprocessingPanel, 'numeric');
            app.ResampleMultiplierEditField.Enable = 'off';
            app.ResampleMultiplierEditField.Value = 1;
            app.GeneralPreprocessingLabel = uilabel(app.GeneralPreprocessingPanel);
            app.GeneralPreprocessingLabel.Enable = 'off';
            app.GeneralPreprocessingLabel.Text = 'General Preprocessing';
            app.DetectInvalidSamplesPanel = uipanel(app.Panel);
            app.DetectInvalidSamplesCheckBox = uicheckbox(app.DetectInvalidSamplesPanel);
            app.DetectInvalidSamplesCheckBox.Enable = 'off';
            app.DetectInvalidSamplesCheckBox.Text = 'Detect Invalid Samples';
            app.DetectInvalidSamplesCheckBox.Value = true;
            app.FrontPaddingsEditFieldLabel = uilabel(app.DetectInvalidSamplesPanel);
            app.FrontPaddingsEditFieldLabel.Enable = 'off';
            app.FrontPaddingsEditFieldLabel.Text = 'Front Padding (s)';
            app.FrontPaddingEditField = uieditfield(app.DetectInvalidSamplesPanel, 'numeric');
            app.FrontPaddingEditField.Enable = 'off';
            app.RearPaddingsEditFieldLabel = uilabel(app.DetectInvalidSamplesPanel);
            app.RearPaddingsEditFieldLabel.Enable = 'off';
            app.RearPaddingsEditFieldLabel.Text = 'Rear Padding (s)';
            app.RearPaddingEditField = uieditfield(app.DetectInvalidSamplesPanel, 'numeric');
            app.RearPaddingEditField.Enable = 'off';
            app.MergeInvalidsGapsEditFieldLabel = uilabel(app.DetectInvalidSamplesPanel);
            app.MergeInvalidsGapsEditFieldLabel.Enable = 'off';
            app.MergeInvalidsGapsEditFieldLabel.Text = 'Merge Invalids Gap (s)';
            app.MergeInvalidsGapEditField = uieditfield(app.DetectInvalidSamplesPanel, 'numeric');
            app.MergeInvalidsGapEditField.Enable = 'off';
            app.ValidRangeUpper01Label = uilabel(app.DetectInvalidSamplesPanel);
            app.ValidRangeUpper01Label.Enable = 'off';
            app.ValidRangeUpper01Label.Text = 'Valid Range Upper (0-1)';
            app.ValidRangeUpperEditField = uieditfield(app.DetectInvalidSamplesPanel, 'numeric');
            app.ValidRangeUpperEditField.Limits = [0 1];
            app.ValidRangeUpperEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeRange, true);
            app.ValidRangeUpperEditField.Enable = 'off';
            app.ValidRangeUpperEditField.Tooltip = {'Proportion of the max/min of the pupil data (between 0 and 1). Samples that fall below this threshold will be treated as invalid.'};
            app.ValidRangeUpperEditField.Value = 1;
            app.RangeCheckBox = uicheckbox(app.DetectInvalidSamplesPanel);
            app.RangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotToggle, true);
            app.RangeCheckBox.Enable = 'off';
            app.RangeCheckBox.Text = 'Display Range';
            app.ValidRangeLower01Label = uilabel(app.DetectInvalidSamplesPanel);
            app.ValidRangeLower01Label.Enable = 'off';
            app.ValidRangeLower01Label.Text = 'Valid Range Lower (0-1)';
            app.ValidRangeLowerEditField = uieditfield(app.DetectInvalidSamplesPanel, 'numeric');
            app.ValidRangeLowerEditField.Limits = [0 1];
            app.ValidRangeLowerEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeRange, true);
            app.ValidRangeLowerEditField.Enable = 'off';
            app.ValidRangeLowerEditField.Tooltip = {'Proportion of the max/min of the pupil data (between 0 and 1). Samples that fall below this threshold will be treated as invalid.'};
            app.ValidRangeLowerEditField.Value = 0.3;
            app.ApplyAlgorithmButton = uibutton(app.Panel, 'push');
            app.ApplyAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAlgorithm, true);
            app.ApplyAlgorithmButton.Enable = 'off';
            app.ApplyAlgorithmButton.Text = 'Apply Algorithm';
            app.ManuallyEditPlotButton = uibutton(app.Panel, 'push');
            app.ManuallyEditPlotButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchManualEditor, true);
            app.ManuallyEditPlotButton.Enable = 'off';
            app.ManuallyEditPlotButton.Text = 'Manually Edit Plot';
            app.InterpolationOptionsPanel = uipanel(app.Panel);
            app.MergeArtifactsGapsEditFieldLabel = uilabel(app.InterpolationOptionsPanel);
            app.MergeArtifactsGapsEditFieldLabel.Enable = 'off';
            app.MergeArtifactsGapsEditFieldLabel.Text = 'Merge Artifacts Gap (s)';
            app.MergeArtifactsGapEditField = uieditfield(app.InterpolationOptionsPanel, 'numeric');
            app.MergeArtifactsGapEditField.Enable = 'off';
            app.MaxArtifactDurationsEditFieldLabel = uilabel(app.InterpolationOptionsPanel);
            app.MaxArtifactDurationsEditFieldLabel.Enable = 'off';
            app.MaxArtifactDurationsEditFieldLabel.Text = 'Max Artifact Duration (s)';
            app.MaxArtifactDurationEditField = uieditfield(app.InterpolationOptionsPanel, 'numeric');
            app.MaxArtifactDurationEditField.Enable = 'off';
            app.MaxArtifactDurationEditField.Value = 2;
            app.MaxArtifactTreatmentButtonGroup = uibuttongroup(app.InterpolationOptionsPanel);
            app.IgnoreButton = uiradiobutton(app.MaxArtifactTreatmentButtonGroup);
            app.IgnoreButton.Tag = 'ignore';
            app.IgnoreButton.Enable = 'off';
            app.IgnoreButton.Text = 'Ignore';
            app.IgnoreButton.Value = true;
            app.InterpolateButton = uiradiobutton(app.MaxArtifactTreatmentButtonGroup);
            app.InterpolateButton.Tag = 'interpolate';
            app.InterpolateButton.Enable = 'off';
            app.InterpolateButton.Text = 'Interpolate';
            app.NaNImputeButton = uiradiobutton(app.MaxArtifactTreatmentButtonGroup);
            app.NaNImputeButton.Tag = 'nanimpute';
            app.NaNImputeButton.Enable = 'off';
            app.NaNImputeButton.Text = 'NaN Impute';
            app.InterpolationOptionsLabel = uilabel(app.InterpolationOptionsPanel);
            app.InterpolationOptionsLabel.Enable = 'off';
            app.InterpolationOptionsLabel.Text = 'Interpolation Options';
            app.VelocityPlotAxis = uiaxes(app.UIFigure);
            title(app.VelocityPlotAxis, 'Velocity Plot')
            xlabel(app.VelocityPlotAxis, 'Time (s)')
            ylabel(app.VelocityPlotAxis, 'Unknown Units')
            app.PupilPlotAxis = uiaxes(app.UIFigure);
            title(app.PupilPlotAxis, 'Pupil Plot')
            xlabel(app.PupilPlotAxis, 'Time (s)')
            ylabel(app.PupilPlotAxis, 'Unknown Units')
            app.PupilPlotAxis.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ET_RemoveArtifacts_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUpFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end